classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        ThresholdValueEditField       matlab.ui.control.NumericEditField
        ThresholdValueEditFieldLabel  matlab.ui.control.Label
        PreprocessButton              matlab.ui.control.Button
        BlueWeightEditField           matlab.ui.control.NumericEditField
        BlueWeightEditFieldLabel      matlab.ui.control.Label
        GreenWeightEditField          matlab.ui.control.NumericEditField
        GreenWeightEditFieldLabel     matlab.ui.control.Label
        RedWeightEditField            matlab.ui.control.NumericEditField
        RedWeightEditFieldLabel       matlab.ui.control.Label
        ModifyRGBtoGrayCheckBox       matlab.ui.control.CheckBox
        ThresholdingCheckBox          matlab.ui.control.CheckBox
        CKSmoothingCheckBox           matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ModifyRGBtoGrayCheckBox
        function ModifyRGBtoGrayCheckBoxValueChanged(app, event)
            value = app.ModifyRGBtoGrayCheckBox.Value;

            %Enable re-weighting of RGB to gray
            if value
                app.BlueWeightEditField.Enable=true;
                app.GreenWeightEditField.Enable=true;
                app.RedWeightEditField.Enable=true;
            else
                app.BlueWeightEditField.Enable=false;
                app.GreenWeightEditField.Enable=false;
                app.RedWeightEditField.Enable=false;
            end
        end

        % Button pushed function: PreprocessButton
        function PreprocessButtonPushed(app, event)
            %Preprocess the data

            topFolder=uigetdir(pwd,'Please select your data folder');
            topFolder=erase(topFolder,[pwd,filesep]);


                if app.CKSmoothingCheckBox.Value && app.ThresholdingCheckBox.Value

                    % Recursively get all folders inside topFolder
                    allFolders = genpath(topFolder);  % Returns a colon-separated list of paths
                    
                    % Split into individual folder paths
                    folderList = strsplit(allFolders, pathsep);
                    
                    % Loop through each folder
                    for i = 1:length(folderList)
                        folderPath = folderList{i};
                        
                        if isempty(folderPath)
                            continue; %if folder is empty, pass to next iteration of loop
                        end
                    
                        % Get png and jpg files in this folder only (non-recursive)
                        pngFiles = dir(fullfile(folderPath, '*.png'));
                        jpgFiles = dir(fullfile(folderPath, '*.jpg'));
                        files=[pngFiles;jpgFiles]; %combine into one array
                    
                        % Skip folders with no PNGs
                        if isempty(files)
                            continue;
                        end
                        
                        %Keeping track of indices in loop
                        file_count=0;
                        
                        %Loop through files in one folder
                        for file=files'
                        
                            file_count=file_count+1;
                            
                            %Keeping track of path of image for storage
                            imgPath=fullfile(file.folder,file.name);
                
                            %Read and store image
                            tmp=imread(imgPath);
                            
                            Rw=app.RedWeightEditField.Value;
                            Gw=app.GreenWeightEditField.Value;
                            Bw=app.BlueWeightEditField.Value;
        
                            imdata=Rw*tmp(:,:,1)+Gw*tmp(:,:,2)+Bw*tmp(:,:,3);
                            imdata=im2double(imdata); %Converts from 0-255 to 0-1 scale
    
                            %% Filter first
                            %Initialize image to filter
                            [N,M]=size(imdata);
                    
                            %Length of moving (forwards and backwards) windows
                            %Averaging over multiple window lengths takes much more time
                            m1=4;
                            m2=8;
                            m3=16;
          
                            p=1;
                    
                            for i=1:3 %repeated application of filter
                            
                            
                                for row=1:N
                                    x=imdata(row,:);
                                    [y1,~,~]=ckfilter(x,m1,p);
                                    [y2,~,~]=ckfilter(x,m2,p);
                                    [y3,~,~]=ckfilter(x,m3,p);
                                    y=(1/3)*y1+(1/3)*y2+(1/3)*y3; %average over windows
                            
                                    imdata(row,:)=y;
                                end
                            
                                for col=1:M
                                    x=imdata(:,col);
                                    [y1,~,~]=ckfilter(x,m1,p);
                                    [y2,~,~]=ckfilter(x,m2,p);
                                    [y3,~,~]=ckfilter(x,m3,p);
                                    y=(1/3)*y1+(1/3)*y2+(1/3)*y3; %average over windows
                                    imdata(:,col)=y;
                                end
                            end
        
                            %% Thresholding second
                            thresh=app.ThresholdValueEditField.Value;
        
                            %backDomain=(imdata<thresh); %boolean array
                            objDomain=(imdata>=thresh);
        
                            %threshData=1*backDomain+255*objDomain;
                            imdata=objDomain;
        
                            imageFolder='preprocessed';
                
                            if ~exist(imageFolder,'dir')
                                mkdir(imageFolder)
                            end
                            
                            %pwd for "path to working directory"
                            
                            %Gets path removing topFolder/ (e.g. angiosperms/)
                            relativeFolder=erase(folderPath, topFolder);
                
                            %Create output file path
                            imageOutput=fullfile(imageFolder,relativeFolder);
                
                            %Constructing output folders
                            if ~exist(imageOutput,'dir')
                                mkdir(imageOutput)
                            end
        
                            
                            %Naming files and giving them paths
                            [~,name,~]=fileparts(file.name);
        
                            imageTitle=[name,'_proc.jpg'];
                
                            imageFilePath=fullfile(imageOutput,imageTitle);
                
                            %Writing files to appropriate path
                            imwrite(imdata,imageFilePath); %image file


                end %end conditional statements


                end %End loop through folders
            end
            
        end

        % Value changed function: ThresholdingCheckBox
        function ThresholdingCheckBoxValueChanged(app, event)
            value = app.ThresholdingCheckBox.Value;
            if value
                app.ThresholdValueEditField.Enable=true;
            else
                app.ThresholdValueEditField.Enable=false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.CKSmoothingCheckBox = uicheckbox(app.GridLayout);
            app.CKSmoothingCheckBox.Text = 'CK Smoothing';
            app.ThresholdingCheckBox = uicheckbox(app.GridLayout);
            app.ThresholdingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ThresholdingCheckBoxValueChanged, true);
            app.ThresholdingCheckBox.Text = 'Thresholding';
            app.ModifyRGBtoGrayCheckBox = uicheckbox(app.GridLayout);
            app.ModifyRGBtoGrayCheckBox.ValueChangedFcn = createCallbackFcn(app, @ModifyRGBtoGrayCheckBoxValueChanged, true);
            app.ModifyRGBtoGrayCheckBox.Text = 'Modify RGB to Gray';
            app.RedWeightEditFieldLabel = uilabel(app.GridLayout);
            app.RedWeightEditFieldLabel.Enable = 'off';
            app.RedWeightEditFieldLabel.Text = 'Red Weight';
            app.RedWeightEditField = uieditfield(app.GridLayout, 'numeric');
            app.RedWeightEditField.Enable = 'off';
            app.RedWeightEditField.Value = 0.299;
            app.GreenWeightEditFieldLabel = uilabel(app.GridLayout);
            app.GreenWeightEditFieldLabel.Enable = 'off';
            app.GreenWeightEditFieldLabel.Text = 'Green Weight';
            app.GreenWeightEditField = uieditfield(app.GridLayout, 'numeric');
            app.GreenWeightEditField.Enable = 'off';
            app.GreenWeightEditField.Value = 0.587;
            app.BlueWeightEditFieldLabel = uilabel(app.GridLayout);
            app.BlueWeightEditFieldLabel.Enable = 'off';
            app.BlueWeightEditFieldLabel.Text = 'Blue Weight';
            app.BlueWeightEditField = uieditfield(app.GridLayout, 'numeric');
            app.BlueWeightEditField.Enable = 'off';
            app.BlueWeightEditField.Value = 0.114;
            app.PreprocessButton = uibutton(app.GridLayout, 'push');
            app.PreprocessButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessButtonPushed, true);
            app.PreprocessButton.Text = 'Preprocess';
            app.ThresholdValueEditFieldLabel = uilabel(app.GridLayout);
            app.ThresholdValueEditFieldLabel.Enable = 'off';
            app.ThresholdValueEditFieldLabel.Text = 'Threshold Value';
            app.ThresholdValueEditField = uieditfield(app.GridLayout, 'numeric');
            app.ThresholdValueEditField.Enable = 'off';
            app.ThresholdValueEditField.Value = 0.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end